classdef Temperature_manipulation_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        SettingsMenu                    matlab.ui.container.Menu
        EditpropertiesMenu              matlab.ui.container.Menu
        GainEditField                   matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        Avgtempforlast2sEditField       matlab.ui.control.NumericEditField
        Avgtempforlast2sEditFieldLabel  matlab.ui.control.Label
        BaselinetempCEditField          matlab.ui.control.NumericEditField
        BaselinetempCEditFieldLabel     matlab.ui.control.Label
        TempcontrolButton               matlab.ui.control.StateButton
        TargettemperatureCSpinner       matlab.ui.control.Spinner
        TargettemperatureCSpinnerLabel  matlab.ui.control.Label
        CurrentchipvoltageVEditField    matlab.ui.control.NumericEditField
        CurrentchipvoltageVEditFieldLabel  matlab.ui.control.Label
        CurrenttemperatureCEditField    matlab.ui.control.NumericEditField
        CurrenttemperatureCEditFieldLabel  matlab.ui.control.Label
        SamplingrateHzEditField         matlab.ui.control.NumericEditField
        SamplingrateHzEditFieldLabel    matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StrartscnanningButton           matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        file_name = "";         % name of the file where data is saved
        file_path               % location of the save file

        uno                     % variable for the microcontroller
        DIHardware              % variable for the data acquisiton hardware
        DI2008                  % variable for the data acquisiton hardware

        target_temp = 35;       % temperature goal
        baseline = 34;          % starting temperature


        running = 0;            % state of the scanner
        control_active = 0;     % stores the state describing if temp control is active or not 
        t = datetime;           % variable to get time from
        sampling_rate = 100;    % sampling frequency in Hz
        v_settings              % voltage settings app
        saved_settings          % stores settings loaded from file 
        pwm_output = [];        % stores the generated output for logging purposes
        new_output = 1;         % initialization of the first output (1 means around 20% of maximal output)
        gain = 0;               % gain factor used to calculate the output
        intra_gain = 0.005;     % user changeable gain value that defines the "gain" later on
        index = 0;              % used for updating live display

        error = [];             % error function 
        data = [];              % stores the temperature data  
    end

    properties (Access = public)
        V_supply = 1;           % voltage of the supply used 
        V_chip_max = 1;         % maximal operating voltage of Peltier chip
        max_temp = 40;          % maximal temperature measured by the thermocouple, safety limit
        V_max_heat;             % maximal allowed voltage of the peltier chip when heating
        V_max_cool;             % maximal allowed voltage of the peltier chip when cooling
    end

    methods (Access = private)


        function temp_control(app)

            if  app.file_name == ""         %if there is no file name specified yet it brings up the file saving menu
                app.file_selection;         %file saving menu
            end

            %Start scanning
            try
                app.DI2008.AcquisitionStartAsync.Result;
            catch ME
                disp(ME.message);
            end


            DI_TempDataArray = {};                              % temporarily holds data while scanning
            prev_point = 0;                                     % stores the previous temperature value for visualization

            %% Data acquisition & processing
            tic                                                 % starting a timer for baseline temperature measurement
               
            while app.DI2008.IsAcquiring() && app.running==1    % while data acquisition is active
                try
                    app.DI2008.ReadDataAsync.Result;            % refreshing the buffer DataIn with new data
                catch ME
                    disp(ME.message);
                    break
                end

                DI_TempDataArray = [];                          % initializing temporary data array to hold new data
                mode = 0;                                       % initializing array used to store output mode data (1 for heating, -1 for cooling, 0 for idling)
                
                % converting .NET array data to a double array with a column for every active channel
                for ColumnChan = 1:app.DI2008.Channels.Count
                    ch = app.DI2008.Channels.Item(ColumnChan-1);
                    DI_NewData = ch.DataIn.ToArray;
                    DI_NewData = reshape(DI_NewData.double,[],1);

                    if ColumnChan == 1
                        DI_TempDataArray = DI_NewData;
                    else
                        DI_TempDataArray(:,ColumnChan) = DI_NewData;
                    end

                    app.data = [app.data ;mean(DI_NewData,1)];                      % storing the collected temp data
                    app.error = [app.error; mean(app.target_temp - DI_NewData,1)];
                    
                    ch.DataIn.Clear;                                                % clearing data from the buffer for the next cycle
                end


                    app.gain = app.intra_gain * abs(app.error(end));                % gain factor initialization according to user input

                % cooling procedure (desired temperature is lower than the starting one)
                if app.target_temp <= app.baseline &&  app.control_active==1 && baseline_timer > 3 
                    
                    % if current temperature is higher than the target temperature, output is increased by (gain*error)%
                    if app.data(end)> app.target_temp                  
                        app.new_output = app.new_output * (1+app.gain);
                    end
                    
                    % if current temperature is lower than the target, output is decreased by (gain*error)%
                    if app.data(end)< app.target_temp
                        app.new_output = app.new_output * (1-app.gain);
                    end
                    
                    % runs a safety check to ensure that the output is right for the arduino and nothing gets damaged
                    app.safety_check(app.V_max_cool); 
                    
                    writePWMVoltage(app.uno,'D5',0);               % setting pin 5 to low to disable heating
                    writePWMVoltage(app.uno,'D6',app.new_output);  % setting pin 6 for cooling with the output value
                    mode = -1;                                     % the state of the system is stored for later recording (-1 = cooling)
                    

                % heating procedure (desired temperature is higher than the starting one)
                elseif app.target_temp > app.baseline && app.control_active==1 && baseline_timer > 3
                    
                    % if current temperature is higher than the target temperature, output is decreased by gain%
                    if app.data(end)> app.target_temp
                        app.new_output = app.new_output * (1-app.gain);
                    end
                    
                    % if current temperature is lower than the target temperature, output is increased by gain%
                    if app.data(end)< app.target_temp
                        app.new_output = app.new_output * (1+app.gain);
                    end
                    
                    % safety check again
                    app.safety_check(app.V_max_heat);
                    
                        writePWMVoltage(app.uno,'D6',0);               % setting pin 5 to low to disable heating
                        writePWMVoltage(app.uno,'D5',app.new_output);  % setting pin 6 for cooling with the output value
                        mode = 1;                                      % state of the system stored (1 = heating)
                    
                        
                % in case neither heating or cooling is required the output is turned off        
                else
                     writePWMVoltage(app.uno,'D5',0);                            
                     writePWMVoltage(app.uno,'D6',0);  
                end
                
               
                app.pwm_output = [app.pwm_output; app.new_output];      % storing the newly generated output for later recordnig
            
                app.CurrentchipvoltageVEditField.Value = app.V_supply * (app.new_output/5); % updates the readout of chip voltage 
                app.CurrenttemperatureCEditField.Value = mean(DI_NewData,1);                % updates the readout of current temp
                app.BaselinetempCEditField.Value = app.baseline;                            % updates the readout of baseline


                %% Saving data to file
                app.save_data_to_file(DI_NewData,mode);

                % plotting
                app.update_plot(prev_point,mean(DI_NewData,1));% updates the graph on the gui
                drawnow();
                
                prev_point = mean(DI_NewData);                  % stores the value of the current temp datapoint to use as the previous one in the next cycle
                app.index = app.index+1;                        % used for making the live temp display scroll

                
                baseline_timer = toc;                                   % timer used for baseline measurement
                if baseline_timer<3                                     % 3 seconds for determining baseline temp
                    app.baseline = mean(app.data,1);                    % saving baseline 
                    app.BaselinetempCEditField.Value = app.baseline;    % updating baseline readout
                end
                
                % average temperature calculation for the last 2 sec
                if length(app.data)>50
                app.Avgtempforlast2sEditField.Value = mean(app.data(end-50:end));
                end
            end

            app.DI2008.AcquisitionStopAsync.Result;     % stiop scanning

            app.DI2008.Dispose;                         % disconnecting the  data acquisition device 
            app.DIHardware.Dispose;
            
        end

        
        function file_selection(app)                            
            filter = {'*.csv';'*.xls'};                                                              % filter used for selecting file types
            [app.file_name,app.file_path] = uiputfile(filter,'File Selection','temp_recording.csv'); % opens the file selection ui menu
            
            writematrix(["Time of recording:", app.t.Year ,app.t.Month, app.t.Day,'','',''],...      % writing basic info into the header of file
                fullfile(app.file_path,app.file_name),'WriteMode','append');
            writematrix(["Sampling rate:", app.sampling_rate,'','','','',''],...
                fullfile(app.file_path,app.file_name),'WriteMode','append');
            writematrix(["Temperature (C)",'target (C)', "Mode",'output', "hour","min","sec"],...
                fullfile(app.file_path,app.file_name),'WriteMode','append');
        end

        
        function safety_check(app,limit)                   
            app.new_output(app.new_output<0) = 0;             % if values in the newly produced output are too high or too low...
            app.new_output(app.new_output>5) = 5;             % they are capped so the arduino can execute the commands
            
            % limits the voltage the peltier chip receives to the specified
            % limit to avoid overheating or freezing of the tissue
            app.new_output((app.new_output * app.V_supply / 5) >  limit) =  limit * 5 /app.V_supply;

            % if the measured tempretaure is too high or too low it sets
            % the previous output as the current one to avoid damage
            if app.data(end) >= app.max_temp || app.data(end) <= 0     
                app.new_output = app.pwm_output(end);
            end
        end


        function save_data_to_file(app,DI_NewData,mode) 
            local_t=datetime;                                   % time varible used in writing the exact time of measurements
            t_mat = [local_t.Hour local_t.Minute local_t.Second];

            % saves incoming temperature data, taget temp, state of the system, output and time to the specified file
            writematrix([mean(DI_NewData,1),app.target_temp, mode, app.new_output,t_mat],fullfile(app.file_path,app.file_name),'WriteMode','append'); 
           
            if length(app.data) >= 2^16                         % if the length of data stored in memory exceeds the ... 
                app.data = app.data(end);                       % limit it gets deleted from the memory leaving only the last data point
            end                                                               
        end

        
        function update_plot(app,prev_point,temp_data)          % updates the data plot
           
            if app.index>1
                plot(app.UIAxes,[app.index-1,app.index],[prev_point,temp_data],'r-');               % connects the previous point with the...
                hold(app.UIAxes,'on');                                                              % most recent one 
                plot(app.UIAxes,[app.index-1, app.index],[app.target_temp,app.target_temp],'g-');   % draws the target temperature on the plot
                
                
                % visual aides helping to determine the trend and
                % fluctuations in the temperature curve on the gui graph
                % (horizontal lines of different color spaced 0.05 degrees C apart
                
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[app.index-1,app.index],[app.target_temp - 0.15,app.target_temp-0.15],'Color',[0.3 0 0.3]);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[app.index-1,app.index],[app.target_temp + 0.15,app.target_temp+0.15],'Color',[0.3 0 0.3]);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[app.index-1,app.index],[app.target_temp - 0.1,app.target_temp-0.1],'Color',[0.6 0 0.6]);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[app.index-1,app.index],[app.target_temp + 0.1,app.target_temp+0.1],'Color',[0.6 0 0.6]);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[app.index-1,app.index],[app.target_temp - 0.05,app.target_temp-0.05],'Color',[1 0 1]);
                hold(app.UIAxes,'on');
                plot(app.UIAxes,[app.index-1,app.index],[app.target_temp + 0.05,app.target_temp+0.05],'Color',[1 0 1]);
                
            else
                cla(app.UIAxes);                                % if there is no previous point just plot the current one 
                plot(app.UIAxes,app.index,temp_data);
                hold(app.UIAxes,'on');
            end
            if app.index >=100                                  % if we have reached the end of the scrolling window start over from the far left
                app.index=0;
                cla(app.UIAxes);
            end
        end
        
        function load_settings_from_file(app)                   % loads settings from the "Settings" file located
            app.saved_settings = readmatrix('Settings.csv');
            app.V_supply = app.saved_settings(1);
            app.V_chip_max = app.saved_settings(2);
            app.V_max_cool = app.saved_settings(3);
            app.V_max_heat = app.saved_settings(4);
            app.max_temp = app.saved_settings(5);
            
        end
        
        % saves settings to the "Settings" file which acts as a long term memory for the program 
        function save_settings_to_file(app)                     
            writematrix(["V_supply","V_chip_max","V_max_cool","V_max_heat","max_temp"],'Settings.csv','WriteMode','overwrite');
            writematrix([app.V_supply,app.V_chip_max,app.V_max_cool,app.V_max_heat ,app.max_temp],'Settings.csv','WriteMode','append');
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.uno = arduino;                  %creating connection with arduino board
            configurePin(app.uno,'D6','PWM');   %setting the pinmode on pin 6
            configurePin(app.uno,'D5','PWM');   %setting the pin mode on pin 5


            %% Dataq2008 initialization and channel setup

            % makeing the .NET DATAQ SDK assembly visible to matlab
            NET.addAssembly('C:\Windows\Microsoft.NET\assembly\GAC_MSIL\Dataq\v4.0_2.1.0.0__9767f47f9c295223\Dataq.dll');
            NET.addAssembly('C:\WINDOWS\Microsoft.NET\assembly\GAC_MSIL\Dataq.Devices\v4.0_2.1.0.0__9767f47f9c295223\Dataq.Devices.dll');
            NET.addAssembly('C:\WINDOWS\Microsoft.NET\assembly\GAC_MSIL\Dataq.Devices.Main\v4.0_2.1.0.0__9767f47f9c295223\Dataq.Devices.Main.dll');
            NET.addAssembly('C:\WINDOWS\Microsoft.NET\assembly\GAC_MSIL\Dataq.Platforms\v4.0_2.1.0.0__9767f47f9c295223\Dataq.Platforms.dll');
            NET.addAssembly('C:\WINDOWS\Microsoft.NET\assembly\GAC_MSIL\Dataq.Protocols\v4.0_2.1.0.0__9767f47f9c295223\Dataq.Protocols.dll');
            
            app.DIHardware = Dataq.Misc.Discovery.BySerialAsync('609D1510'); % locating the DI-2008 by the serial number
            app.DI2008 = app.DIHardware.Result;                              
            try
                app.DI2008.ConnectAsync.Result;                              % connecting to the device
            catch ME
                disp(ME.message);
            end
            
            % initialize the device
            app.DI2008.Channels.Clear();                                                % clearing the channels on startup
            app.DI2008.Protocol.SetLedColorAsync(Dataq.Protocols.Enums.LedColor1.Red);  % setting the led color


            SupportedChannelTypes = app.DI2008.SupportedChannels.GetEnumerator(); % getting supported channel types
            SupportedChannelTypes.MoveNext();
            SupportedChannelTypes.MoveNext();
            ThermocoupleVoltageIn = SupportedChannelTypes.Current.Key();          % setting up thermocouple channel     


            % configuring the thermocouple channel
            ThermocoupleChan = app.DI2008.ChannelFactory(ThermocoupleVoltageIn,1);      % ch1 set to thermocouple
            ThermocoupleChan.AcquisitionMode = Dataq.Devices.DI2008.AverageSample;      % setting acquisition mode           
            ThermocoupleChan.ThermocoupleType = Dataq.Devices.DI2008.ThermocoupleTypeT; % setting thermocouple type
            ThermocoupleChan.DataIn.Capacity = 10;                                      % setting the amount of data stored at once
            app.DI2008.SetSampleRateOnChannels(app.sampling_rate);                      % setting the sampling rate on channels

            
            try
                app.DI2008.InitializeAsync.Result;                   % configure the device as previously defined
            catch ME
                disp(ME.message);
            end
            
            app.load_settings_from_file;                             % loading saved settings from the "settings" file
            
            app.TargettemperatureCSpinner.Value = app.target_temp;   % initializing the target temperature field on the gui
            app.GainEditField.Value = app.intra_gain;                % initializing the gain field on the gui


        end

        % Callback function
        function filenameEditFieldValueChanged(app, event)
            app.file_name_in = app.filenameEditField.Value;

        end

        % Callback function
        function TargettemperatureCEditFieldValueChanged(app, event)
            % sets the target temperature to the value specified by the
            % user
            app.target_temp = app.TargettemperatureCEditField.Value;

        end

        % Button pushed function: StrartscnanningButton
        function StrartscnanningButtonPushed(app, event)
            % pressing the "Start" button starts the scanning process
            app.running =1;
            app.temp_control;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
           % pressing the "stop" button terminates the scanning and saves
           % the used settings to the "settings" file
            app.running=0;
            app.save_settings_to_file;
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            app.file_selection; % file selection menu activates
        end

        % Menu selected function: EditpropertiesMenu
        function EditpropertiesMenuSelected(app, event)
            % handles the submenu of settings menu
            app.SettingsMenu.Enable = 'off'; % user cant click on the menu while it is already active
            
            % opens up a dialog window where settings can be changed 
            %this second window is stored as "v_settings" object in the
            %main application
            app.v_settings = V_settings_app(app,app.V_supply,app.V_chip_max,app.max_temp,app.V_max_cool,app.V_max_heat);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.DI2008.Dispose;      % disconnecting the  data acquisition device when the app is closed
            app.DIHardware.Dispose;  
            delete(app.v_settings)   % deleting the second app (where settings might have been changed by the user)       
            delete(app)              % deletes the main app

        end

        % Value changed function: SamplingrateHzEditField
        function SamplingrateHzEditFieldValueChanged(app, event)
            % user can set the sampling rate by changing the "sampling rate"
            % field on the gui
            app.sampling_rate = app.SamplingrateHzEditField.Value;

        end

        % Value changed function: TargettemperatureCSpinner
        function TargettemperatureCSpinnerValueChanged(app, event)
            % user can change the target temp using the target temperature
            % field on the gui
            app.target_temp = app.TargettemperatureCSpinner.Value;

        end

        % Value changed function: TempcontrolButton
        function TempcontrolButtonValueChanged(app, event)
            % once the scanning has started pressing this button enables
            % the temperature manipulation to begin 
            
            % By pressing it again the temp manipulation gets disabled
            app.control_active = app.TempcontrolButton.Value;
            
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            % user can change the gain value using the "gain" gui field
            app.intra_gain = app.GainEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Tooltip = {'Choose custom name and file path'};
            app.SaveasMenu.Text = 'Save as';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.EditpropertiesMenu = uimenu(app.SettingsMenu);
            app.EditpropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @EditpropertiesMenuSelected, true);
            app.EditpropertiesMenu.Tooltip = {'if you use different batteries this is how you tell the program'};
            app.EditpropertiesMenu.Text = 'Edit properties';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature data')
            ylabel(app.UIAxes, 'Temperature (C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40];
            app.UIAxes.YTickLabel = {'-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'};
            app.StrartscnanningButton = uibutton(app.UIFigure, 'push');
            app.StrartscnanningButton.ButtonPushedFcn = createCallbackFcn(app, @StrartscnanningButtonPushed, true);
            app.StrartscnanningButton.Text = 'Strart scnanning';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.SamplingrateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingrateHzEditFieldLabel.Text = 'Sampling rate (Hz)';
            app.SamplingrateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingrateHzEditField.Limits = [1 2000];
            app.SamplingrateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingrateHzEditFieldValueChanged, true);
            app.SamplingrateHzEditField.Value = 100;
            app.CurrenttemperatureCEditFieldLabel = uilabel(app.UIFigure);
            app.CurrenttemperatureCEditFieldLabel.Text = 'Current temperature (C)';
            app.CurrenttemperatureCEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrenttemperatureCEditField.Editable = 'off';
            app.CurrentchipvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentchipvoltageVEditFieldLabel.Text = 'Current chip voltage (V)';
            app.CurrentchipvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentchipvoltageVEditField.Editable = 'off';
            app.TargettemperatureCSpinnerLabel = uilabel(app.UIFigure);
            app.TargettemperatureCSpinnerLabel.Text = 'Target temperature (C)';
            app.TargettemperatureCSpinner = uispinner(app.UIFigure);
            app.TargettemperatureCSpinner.Step = 0.5;
            app.TargettemperatureCSpinner.Limits = [-2 40];
            app.TargettemperatureCSpinner.ValueChangedFcn = createCallbackFcn(app, @TargettemperatureCSpinnerValueChanged, true);
            app.TargettemperatureCSpinner.Tooltip = {'Set the target temperature'; '(you have to change it from the deafult)'};
            app.TargettemperatureCSpinner.Value = 37;
            app.TempcontrolButton = uibutton(app.UIFigure, 'state');
            app.TempcontrolButton.ValueChangedFcn = createCallbackFcn(app, @TempcontrolButtonValueChanged, true);
            app.TempcontrolButton.Text = 'Temp control';
            app.BaselinetempCEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinetempCEditFieldLabel.Text = 'Baseline temp (C)';
            app.BaselinetempCEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinetempCEditField.Editable = 'off';
            app.Avgtempforlast2sEditFieldLabel = uilabel(app.UIFigure);
            app.Avgtempforlast2sEditFieldLabel.Text = 'Avg temp for last 2s';
            app.Avgtempforlast2sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Avgtempforlast2sEditField.Editable = 'off';
            app.GainEditFieldLabel = uilabel(app.UIFigure);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 0.005;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Temperature_manipulation_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end