classdef V_settings_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MaximalheatingvoltageVEditField  matlab.ui.control.NumericEditField
        MaximalheatingvoltageVEditFieldLabel  matlab.ui.control.Label
        MaximalcoolingvoltageVEditField  matlab.ui.control.NumericEditField
        MaximalcoolingvoltageVEditFieldLabel  matlab.ui.control.Label
        MaxtempCEditField              matlab.ui.control.NumericEditField
        MaxtempCEditFieldLabel         matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        OkButton                       matlab.ui.control.Button
        MaxchipvoltageVEditField       matlab.ui.control.NumericEditField
        MaxchipvoltageVEditFieldLabel  matlab.ui.control.Label
        SupplyvoltageVEditField        matlab.ui.control.NumericEditField
        SupplyvoltageVEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = public)
        
        supply_voltage    % stores the voltage supply's voltage
        chip_voltage      % stores the peltier chip's maximal operating voltage
        max_temp_setting  % the maximal allowed temperature the thermocouple measures (safety limit)
        max_cooling       % maximal voltage the peltier chip receives while in cooling mode
        max_heating       % maximal voltage the peltier chip receives while in heating mode
    end
    
    properties (Access = private)
        CallingApp        % stores the state of the main app that has called this dialog window 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, v_supp, v_ch, max_tempp, v_max_cool, v_max_heat)
            app.CallingApp = MainApp;                               % the app that has called opened this window is the main app (temp manipulation app)
            
            % initializing the readouts of the gui 
            app.MaxchipvoltageVEditField.Value = v_ch;
            app.SupplyvoltageVEditField.Value = v_supp;
            app.MaxtempCEditField.Value = max_tempp;
            app.MaximalcoolingvoltageVEditField.Value = v_max_cool;
            app.MaximalheatingvoltageVEditField.Value = v_max_heat;
            
            % initializing the variables in this app with the given
            % parameters
            app.supply_voltage = app.CallingApp.V_supply;
            app.chip_voltage = app.CallingApp.V_chip_max;
            app.max_temp_setting = app.CallingApp.max_temp;
            app.max_cooling = app.CallingApp.V_max_cool;
            app.max_heating = app.CallingApp.V_max_heat;
        end

        % Value changed function: SupplyvoltageVEditField
        function SupplyvoltageVEditFieldValueChanged(app, event)
            % supply voltage can be changed by the user in the gui
            app.supply_voltage = app.SupplyvoltageVEditField.Value;
            
        end

        % Value changed function: MaxchipvoltageVEditField
        function MaxchipvoltageVEditFieldValueChanged(app, event)
            % maximal chip voltage can be changed by the user in the gui
            app.chip_voltage = app.MaxchipvoltageVEditField.Value;
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % if user presses the "ok" button all changes made are applied
            % to the main app's variables
            app.CallingApp.V_supply = app.supply_voltage;
            app.CallingApp.V_chip_max = app.chip_voltage;
            app.CallingApp.max_temp = app.max_temp_setting;
            app.CallingApp.V_max_cool = app.max_cooling;
            app.CallingApp.V_max_heat = app.max_heating;
            
            % re-enables the sub menu of the settings menu on the main app
            app.CallingApp.SettingsMenu.Enable = 'on';
            
            % deletes this dialog window and app 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % re-enables the sub menu of the settings menu on the main app
            app.CallingApp.SettingsMenu.Enable = 'on';
            
            % deletes this dialog window and app 
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % re-enables the sub menu of the settings menu on the main app
            app.CallingApp.SettingsMenu.Enable = 'on';
           
            % deletes this dialog window and app 
            delete(app)
            
        end

        % Value changed function: MaxtempCEditField
        function MaxtempCEditFieldValueChanged(app, event)
            % maximal temperature (safety limit) can be changed by the user in the gui
            app.max_temp_setting = app.MaxtempCEditField.Value;
            
        end

        % Value changed function: MaximalcoolingvoltageVEditField
        function MaximalcoolingvoltageVEditFieldValueChanged(app, event)
            % maximal chip voltage during cooling can be changed by the user in the gui
            app.max_cooling = app.MaximalcoolingvoltageVEditField.Value;
            
        end

        % Value changed function: MaximalheatingvoltageVEditField
        function MaximalheatingvoltageVEditFieldValueChanged(app, event)
            % maximal chip voltage during heating can be changed by the user in the gui
            app.max_heating = app.MaximalheatingvoltageVEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SupplyvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.SupplyvoltageVEditFieldLabel.Text = 'Supply voltage (V)';
            app.SupplyvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.SupplyvoltageVEditField.Limits = [0.1 Inf];
            app.SupplyvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @SupplyvoltageVEditFieldValueChanged, true);
            app.SupplyvoltageVEditField.Value = 0.1;
            app.MaxchipvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxchipvoltageVEditFieldLabel.Text = 'Max chip voltage (V)';
            app.MaxchipvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxchipvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @MaxchipvoltageVEditFieldValueChanged, true);
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.MaxtempCEditFieldLabel = uilabel(app.UIFigure);
            app.MaxtempCEditFieldLabel.Text = 'Max temp (C)';
            app.MaxtempCEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxtempCEditField.ValueChangedFcn = createCallbackFcn(app, @MaxtempCEditFieldValueChanged, true);
            app.MaximalcoolingvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.MaximalcoolingvoltageVEditFieldLabel.Text = 'Maximal cooling voltage (V)';
            app.MaximalcoolingvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximalcoolingvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @MaximalcoolingvoltageVEditFieldValueChanged, true);
            app.MaximalheatingvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.MaximalheatingvoltageVEditFieldLabel.Text = 'Maximal heating voltage (V)';
            app.MaximalheatingvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximalheatingvoltageVEditField.ValueChangedFcn = createCallbackFcn(app, @MaximalheatingvoltageVEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = V_settings_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end